classdef eyeCalibration < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        VideoCollectionTab              matlab.ui.container.Tab
        CameraParametersLabel           matlab.ui.control.Label
        PresetsDropDownLabel            matlab.ui.control.Label
        PresetsDropDown                 matlab.ui.control.DropDown
        CameraResolutionEditFieldLabel  matlab.ui.control.Label
        CameraResolutionEditField       matlab.ui.control.NumericEditField
        BoxSizeEditFieldLabel           matlab.ui.control.Label
        BoxSizeEditField                matlab.ui.control.NumericEditField
        BoxOffsetEditFieldLabel         matlab.ui.control.Label
        BoxOffsetEditField              matlab.ui.control.NumericEditField
        CameraResolutionEditField_2     matlab.ui.control.NumericEditField
        UIAxes                          matlab.ui.control.UIAxes
        UIAxes_2                        matlab.ui.control.UIAxes
        SetupCamerasButton              matlab.ui.control.Button
        CollectDataButton               matlab.ui.control.Button
        NumberofframestocollectEditFieldLabel  matlab.ui.control.Label
        NumberofframestocollectEditField  matlab.ui.control.NumericEditField
        DoneSetupButton                 matlab.ui.control.Button
        OfflineEyeTrackingTab           matlab.ui.container.Tab
        RadiusPupilEditFieldLabel       matlab.ui.control.Label
        RadiusPupilEditField            matlab.ui.control.NumericEditField
        RadiusCornealReflectionEditFieldLabel  matlab.ui.control.Label
        RadiusCornealReflectionEditField  matlab.ui.control.NumericEditField
        MinimumFeaturesEditFieldLabel   matlab.ui.control.Label
        MinimumFeaturesEditField        matlab.ui.control.NumericEditField
        radiiCR1EditField_2             matlab.ui.control.NumericEditField
        ImproveImageContrastCheckBox    matlab.ui.control.CheckBox
        SetupEyeTrackingButton          matlab.ui.control.Button
        RunEyeTrackingButton            matlab.ui.control.Button
        edgeThreshEditFieldLabel        matlab.ui.control.Label
        edgeThreshEditField             matlab.ui.control.NumericEditField
        PlotAnalysisCheckBox            matlab.ui.control.CheckBox
        debugCheckBox                   matlab.ui.control.CheckBox
        UIAxes2_2                       matlab.ui.control.UIAxes
        UIAxes3                         matlab.ui.control.UIAxes
        UIAxes3_2                       matlab.ui.control.UIAxes
        RadiusPupilEditField_2          matlab.ui.control.NumericEditField
        CameraDropDownLabel             matlab.ui.control.Label
        CameraDropDown                  matlab.ui.control.DropDown
        UseallthreshCheckBox            matlab.ui.control.CheckBox
        EyeAnalysisTab                  matlab.ui.container.Tab
        CalibrationStimFrequencyEditFieldLabel  matlab.ui.control.Label
        CalibrationStimFrequencyEditField  matlab.ui.control.NumericEditField
        LeftEyeCheckBox                 matlab.ui.control.CheckBox
        Camera2TimestampsCheckBox       matlab.ui.control.CheckBox
        Step1AnalyzeDataPanel           matlab.ui.container.Panel
        RunAnalysisButton_2             matlab.ui.control.Button
        SaccadeWindowEditField_2        matlab.ui.control.NumericEditField
        SaccadeThresholdVideoEditFieldLabel  matlab.ui.control.Label
        SaccadeThresholdVideoEditField  matlab.ui.control.NumericEditField
        SaccadeWindowmsEditFieldLabel   matlab.ui.control.Label
        SaccadeWindowmsEditField        matlab.ui.control.NumericEditField
        SaccadeThresholdMagnetChan1EditFieldLabel  matlab.ui.control.Label
        SaccadeThresholdMagnetChan1EditField  matlab.ui.control.NumericEditField
        MinimumGoodDataLengthEditFieldLabel  matlab.ui.control.Label
        MinimumGoodDataLengthEditField  matlab.ui.control.NumericEditField
        AlignmentMethodDropDownLabel    matlab.ui.control.Label
        AlignmentMethodDropDown         matlab.ui.control.DropDown
        SaccadeThresholdMagnetChan2EditFieldLabel  matlab.ui.control.Label
        SaccadeThresholdMagnetChan2EditField  matlab.ui.control.NumericEditField
        Step2PlotDataPanel              matlab.ui.container.Panel
        PLOT_DesaccededTraces_Button    matlab.ui.control.Button
        PLOT_FramerateHistogram_Button  matlab.ui.control.Button
        PLOT_AlignedTraces_Button       matlab.ui.control.Button
        PLOT_LinearityTraces_Button     matlab.ui.control.Button
        PLOT_DownsampleShortScatter_Button  matlab.ui.control.Button
        PLOT_LinearRegressiononEachSegment_Button  matlab.ui.control.Button
        PlotLinearityUpsampleVideoButton  matlab.ui.control.Button
        UIAxes4                         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        vars % Description
    end
    methods (Access = private)
        
        function fit = desaccadePrep(app, data)
            
            segTime_m1 = 0:(1/app.vars.samplerate_Magnet):(length(data)-1)/app.vars.samplerate_Magnet;
            segLength_m1 = length(data);
            
            y1 = sin(2*pi*1*segTime_m1(:));
            y2 = cos(2*pi*1*segTime_m1(:));
            constant = ones(segLength_m1,1);
            varsTEMP = [y1 y2 constant];
            keep = abs(data) < 5*std(abs(data)) + mean(abs(data));
            
            b = regress(data(keep), varsTEMP(keep,:));
            fit = varsTEMP * b;
        end


        function varargout = eyecam_APP2a(app, vid, vars, eyetrack, nframes)
        
            %% Defaults for missing inputs
            if ~exist('eyetrack','var')
                eyetrack = 0;
            end
            
            if ~exist('nframes','var')
                nframes = 180*30;
            end
            
            %%  Prep
            start(vid(1));
            start(vid(2));
            AAA = figure(3);
            %fhandle = figure(1);
            i = 0;
            
            %% Imgage Color properties
            cMap = gray(256); % Most of the image is grayscale
            cMap(1,:) = [0 0 1]; % Last row is blue.
            cMap(256,:) = [1 0 0]; % Last row is red.
            
            %% Add a figure to begin recording for EyeTrack
            if eyetrack
                screensize = get(0,'Screensize');
                [screensize(3)/2+9  49 screensize(3)/2-16 screensize(4)-132]
                set(gcf, 'Position', [screensize(3)/2+9  49 screensize(3)/2-16 screensize(4)-132]); %
                msgh = msgbox('Press OK to start calibration');
                set(msgh, 'Position', [screensize(3:4)-[630 425] 120 50])
                
                trigger(vid)
                img1 = getdata(vid(1),1)';
                img2 = getdata(vid(2),1)';
                % because of the older logitech camera set up in D253, the images need to be rotates. This is not needed for the ELP cameras
                %img1_all = uint8(zeros(size(img1,1),size(img1,2),nframes)); %D253
                %img2_all = uint8(zeros(size(img1,1),size(img1,2),nframes)); %D253
                img1_all = uint8(zeros(size(img1,2),size(img1,1),nframes));  %D019
                img2_all = uint8(zeros(size(img1,2),size(img1,1),nframes));  %D019
            else
                set(gcf, 'Position', get(0,'Screensize')); % Maximize figure.
            end
            
            
            %% Plot first frame
            
            [img1, ~] = getsnapshot(vid(1));
            [img2, ~] = getsnapshot(vid(2));
            
%             subplot(1,2,1)
%             himg1 = imshow(img1); hold on;
%             colormap(cMap);            
%             plot(size(img1,2)/2, size(img1,1)/2,'+r', 'MarkerSize',1000);
%             subplot(1,2,2)
%             himg2 = imshow(img2); hold on;
%             colormap(cMap);
%             plot(size(img1,2)/2, size(img1,1)/2,'+r', 'MarkerSize',1000);
            
            himg1 = imshow(img1, 'Parent', app.UIAxes);
            colormap(app.UIAxes, cMap);
            plot(app.UIAxes, size(img1,2)/2, size(img1,1)/2,'+r', 'MarkerSize',1000);
            hold(app.UIAxes, 'on')
            
            himg2 = imshow(img2, 'Parent', app.UIAxes_2);
            colormap(app.UIAxes_2, cMap);
            plot(app.UIAxes_2, size(img2,2)/2, size(img2,1)/2,'+r', 'MarkerSize',1000);
            hold(app.UIAxes_2, 'on')


            %% collect images until user closes  window
            tic
            while ~app.vars.videoSetupComplete
                
                % Quit if enough frames are captured
                if eyetrack && i>=nframes
                    close(gcf)
                    pause(.1)
                    break
                end
                
                if ~exist('msgh','var') ||  ~ishandle(msgh)
                    i = i + 1;
                    
                    [img1, ~] = getsnapshot(vid(1));
                    [img2, ~] = getsnapshot(vid(2));
                    
                    time3(i) = toc;
                    
                    if mod(i, 60) == 0
                        fps = 1/(mean(time3(end-58:end) - time3(end-59:end-1)));
                        hold on
                        AAA;
                        scatter(i, fps);
                        hold off
                    end
                    
                else
                    img1  = getdata(vid(1),1);
                    img2  = getdata(vid(2),1);
                end
                
                % hobin don't know why things were rotated but I left them unrotated
                % maybe unrotating them will give me an error later on
                img1 = rot90(img1,-1);
                img1 = rot90(img1,-1);
                img2 = rot90(img2,-1);
                img2 = rot90(img2,-1);
                
                % Store images in large 3D matrix only when doing eyetrack
                if eyetrack  && i > 0
                    %i % counter
                    img1_all(:,:,i) = img1;
                    img2_all(:,:,i) = img2;
                end
                
                % Update Image
                set(himg1, 'CData',img1);
                set(himg2, 'CData',img2);
            end
            
            stop(vid);
            
            %% Define outputs
            if exist('time1','var')
                meanFrameRate = mean(1./diff(time1))
                results.time1 = time1';
                results.time2 = time2';
                results.time3 = time3'; % double check
            end
            
            if nargout==1
                varargout = {results};
            elseif nargout==2
                varargout = {img1 img2};
            end
            
            %% Save images if running an expmt
            if eyetrack
                try
                    % Save the resulting timestamps
                    save('time', 'results');
                    
                    %% Save as tiff    - make sure windows explorer is closed!
                    tic
                    
                    dbstop if error
                    disp('Saving images')
                    
                    saveastiff(img1_all, fullfile(pwd,'img1.tiff'));
                    saveastiff(img2_all, fullfile(pwd,'img2.tiff'));
                    
                    dbclear if error
                    fprintf('\n%d frames saved in current folder\n',i)
                    t = toc;
                    fprintf('%f sec elapsed per 100 images.\n',t/i*100)
                    
                catch msgid
                    disp(msgid.message) % If you get an error here, run the cell above:
                    keyboard            % ("%% Save as tiff")
                end
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            drawnow;
            app.UIFigure.WindowState = 'maximized';
        end

        % Button pushed function: SetupCamerasButton
        function SetupCamerasButtonPushed(app, event)
            % get parameters from gui
            res = [app.CameraResolutionEditField.Value app.CameraResolutionEditField_2.Value];
            boxSize = app.BoxSizeEditField.Value;
            boxOffset = app.BoxOffsetEditField.Value;
            
            % [x_offest, y_offset, width, length]
            roiInitial = [(res-boxSize)/2 boxSize boxSize]+[0 boxOffset 0 0];
            
            % Set the camera specific parameters. (frame rate, exposure, etc...)
            vid = setupvideo_new2019(roiInitial); pause(.1)
            
            % Show preview until figure closed
            % img1 and img2 are 'cubes' or 'stacks' of images (not from experiment)
            app.vars.videoSetupComplete = 0;
            [img1, img2] = eyecam_APP2a(app, vid, app.vars); % ***Run "eyecam_APP(vid);" to see preview at any point ***%
            %[img1, img2] = eyecam(app, vid); % ***Run "eyecam_APP(vid);" to see preview at any point ***%
            
            % Prompt for ROI around one eye - first click on camera image from the left
            % side, then click-drag to select rectangle
            vid = setROI(vid,img1, img2, res, boxSize, boxOffset);
            
        end

        % Button pushed function: CollectDataButton
        function CollectDataButtonPushed(app, event)
            % Prompt User for calibration folder location
            folder = uigetdir(cd, 'Select Calibration Folder');
            
            if exist(fullfile(folder,'img1.tiff'),'file')
                warning('Overwrite')
                delete(fullfile(pwd, 'img1.tiff'),fullfile(pwd,'img2.tiff'))
            end
            
            close all
            eyetrackon = 1;
            nframes = 30*120; % 30*nseconds
            eyecam_APP(app, vid,eyetrackon,nframes); % results = time stamps, images are saves as img1 and 2
            warning on
        end

        % Button pushed function: SetupEyeTrackingButton
        function SetupEyeTrackingButtonPushed(app, event)
            cla(app.UIAxes2_2)
            
            cam = app.CameraDropDown.Value;
            radiiPupil = [app.RadiusPupilEditField.Value app.RadiusPupilEditField_2.Value];
            radiiCR = [app.RadiusCornealReflectionEditField.Value app.radiiCR1EditField_2.Value];
            minfeatures = app.MinimumFeaturesEditField.Value;
            imAdjust = app.ImproveImageContrastCheckBox.Value;

            try
                videoTest_APP(app, cam, radiiPupil, radiiCR,minfeatures,'ImAdjust',imAdjust)
            catch
                disp('Unable to detect pupils with given parameters')
                disp('Please try again')
            end
            
        end

        % Button pushed function: RunEyeTrackingButton
        function RunEyeTrackingButtonPushed(app, event)
            eyeAnalysis_APP(app)
            
        end

        % Button pushed function: RunAnalysisButton_2
        function RunAnalysisButton_2Pushed(app, event)
            app.vars = findmagscaleVel_APP(app, app.vars); 
            app.vars = AlignMagnetAndVideo_APP(app, app.vars);
            app.vars = PLOT_LinearityNew(app, app.vars);
        end

        % Button pushed function: PLOT_DesaccededTraces_Button
        function PLOT_DesaccededTraces_ButtonPushed(app, event)
            
            A = figure('units','normalized','outerposition',[0 0 1 1]);
            desacPLot = tight_subplot(3,1,[.025 .025],[.025 .025],[.015 .01]);
 
            axes(desacPLot(1));
            title('Magnet Channel-1, Unscaled'); hold on;
            plot(app.vars.tmag, app.vars.mag1Vel,'k'); 
            mag1VelPlot_desac = app.vars.mag1Vel;
            mag1VelPlot_desac(app.vars.sacLoc_mag1) = NaN;
            plot(app.vars.tmag, mag1VelPlot_desac,'b', 'LineWidth', .5); 
            plot(app.vars.tmag,app.vars.vars*app.vars.bMag1,'r-', 'LineWidth', 1);
            try
                ylim([median(app.vars.mag1Vel)*-500 median(app.vars.mag1Vel)*500]);
            catch 
                ylim([median(app.vars.mag1Vel)*500 median(app.vars.mag1Vel)*-500]);
            end
            text(1, median(app.vars.mag1Vel)*300, sprintf('Amp = %.3f deg\nr^2 = %.3f\nScale Factor = %.3f deg/V',app.vars.mag1Amp, app.vars.r2mag1, app.vars.scaleCh1),'BackgroundColor','w')
            yticklabels(yticks)
            
            axes(desacPLot(2));
            title('Magnet  Channel-2, Unscaled'); hold on;
            plot(app.vars.tmag, app.vars.mag2Vel,'k');  
            mag2VelPlot_desac = app.vars.mag2Vel;
            mag2VelPlot_desac(app.vars.sacLoc_mag2) = NaN;
            plot(app.vars.tmag, mag2VelPlot_desac,'b', 'LineWidth', .5);  
            plot(app.vars.tmag,app.vars.vars*app.vars.bMag2,'r-', 'LineWidth', 1);
            try
                ylim([median(app.vars.mag2Vel)*-500 median(app.vars.mag2Vel)*500]);
            catch 
                ylim([median(app.vars.mag2Vel)*500 median(app.vars.mag2Vel)*-500]);
            end
            text(1, median(app.vars.mag2Vel)*300, sprintf('Amp = %.3f deg\nr^2 = %.3f\nScale Factor = %.3f deg/V',app.vars.mag2Amp, app.vars.r2mag2, app.vars.scaleCh2),'BackgroundColor','w')
            yticklabels(yticks)
            
            axes(desacPLot(3));
            title('Video'); hold on;
            plot(app.vars.tmag, app.vars.vidVel,'k');  
            vidVelplot_desac = app.vars.vidVel;
            vidVelplot_desac(app.vars.sacLoc_vid) = NaN;
            plot(app.vars.tmag, vidVelplot_desac,'b', 'LineWidth', .5);  
            plot(app.vars.tmag, app.vars.vars*app.vars.bVid,'r-', 'LineWidth', 1);
            try
                ylim([median(app.vars.vidVel)*-500 median(app.vars.vidVel)*500]);
            catch 
                ylim([median(app.vars.vidVel)*500 median(app.vars.vidVel)*-500]);
            end
            text(1, median(app.vars.vidVel)*300, sprintf('Amp = %.3f V\nr^2 = %.3f',app.vars.vidAmp, app.vars.r2vid),'BackgroundColor','w')
            yticklabels(yticks)
            
            % Cosmetics
            xlabel('s')
            linkaxes(desacPLot, 'x')
            xticks(0:5:max(xticks))
            xticklabels(0:5:max(xticks))
            xlim([0, max(app.vars.tmag)])
            box off
            
            % Save Figures
            set(A,'Units','Inches');
            pos = get(A,'Position');
            set(A,'PaperPositionMode','Auto','PaperUnits','Inches','PaperSize',[pos(3), pos(4)])
            print(A, fullfile(cd, 'Summary_Desaccading.pdf'),'-dpdf');
            savefig('Summary_Desaccading.fig')
        end

        % Button pushed function: PLOT_FramerateHistogram_Button
        function PLOT_FramerateHistogram_ButtonPushed(app, event)
            difft1 = diff(app.vars.vidResults.time1);
            difft2 = diff(app.vars.vidResults.time2);
            frametimes = 1./[difft1; difft2];
            
            A = figure('units','normalized','outerposition',[0 0 1 1]);
            frameRatePlot = tight_subplot(1,1,[.1 .025],[.04 .025],[.03 .01]);
            
            frameRatePlot(1);
            histogram(frametimes, 200);
            vline(30)
            title('Framerate Histogram (Both Cameras)')
            ylabel('Frame Amount')
            xlabel('Framerate')
            
            % Save Figures
            set(A,'Units','Inches');
            pos = get(A,'Position');
            set(A,'PaperPositionMode','Auto','PaperUnits','Inches','PaperSize',[pos(3), pos(4)])
            print(A, fullfile(cd, 'Summary_CameraFrameRate.pdf'),'-dpdf');
            savefig('Summary_CameraFrameRate.fig')
        end

        % Callback function
        function TimealignmentofMagnetandVideoButtonPushed(app, event)
            
        end

        % Button pushed function: PLOT_AlignedTraces_Button
        function PLOT_AlignedTraces_ButtonPushed(app, event)
            % Scale the magnet channel 
            mag_aligned_scaled = app.vars.mag_aligned * app.vars.chosenScale;
            
            A = figure('units','normalized','outerposition',[0 0 1 1]);
            AlignedTraces = tight_subplot(1,1,[.1 .025],[.04 .025],[.03 .03]);
            
            AlignedTraces(1);
            plot(app.vars.time_aligned, mag_aligned_scaled)
            ylabel('Chosen Magnet Position (Scaled)')
            yyaxis right
            plot(app.vars.time_aligned, app.vars.vid_aligned)
            xlabel('Time (s)')
            ylabel('Video Position')
            legend('Magnet', 'Video')
            
            % Save Figures
            set(A,'Units','Inches');
            pos = get(A,'Position');
            set(A,'PaperPositionMode','Auto','PaperUnits','Inches','PaperSize',[pos(3), pos(4)]);
            print(A, fullfile(cd, 'MagnetVideoAlignment.pdf'),'-dpdf');
            savefig('MagnetVideoAlignment.fig');
        end

        % Button pushed function: PLOT_LinearityTraces_Button
        function PLOT_LinearityTraces_ButtonPushed(app, event)
            mag1_aligned = app.vars.mag1_aligned;
            mag2_aligned = app.vars.mag2_aligned;
            
            mag1_vel_aligned = app.vars.mag1_vel_aligned;
            mag2_vel_aligned = app.vars.mag2_vel_aligned;
            
            mag1_aligned_scaled = mag1_aligned * app.vars.scaleCh1x;
            mag2_aligned_scaled = mag2_aligned * app.vars.scaleCh2x;
            
            vid_aligned = app.vars.vid_aligned;
            time_aligned = app.vars.time_aligned;
            vidV_aligned = app.vars.vidV_aligned;
            
            keep = app.vars.keep;
            tvid = app.vars.tvid;
            
            mag1_down  = interp1(time_aligned, mag1_aligned_scaled, tvid);            
            mag2_down  = interp1(time_aligned, mag2_aligned_scaled, tvid);
            
            mag1V_down = interp1(time_aligned, mag1_vel_aligned, tvid);            
            mag2V_down = interp1(time_aligned, mag2_vel_aligned, tvid);
            
            vid_down   = interp1(time_aligned, vid_aligned, tvid);
            vidV_down  = interp1(time_aligned, vidV_aligned, tvid);

            keep_down = interp1(time_aligned, double(keep), tvid);
            keep_down(isnan(keep_down)) = 0;
            keep_down = logical(keep_down);
            c = linspace(1,10,length(mag1_aligned_scaled));

            
            %%%%%% Figure A
            A = figure('units','normalized','outerposition',[0 0 1 1]);
            plota = tight_subplot(2,2,[.05 .025],[.05 .025],[.03 .01]); 
            B = figure('units','normalized','outerposition',[0 0 1 1]);
            plotb = tight_subplot(2,2,[.05 .025],[.05 .025],[.03 .01]);            


            %%%%%% Channel 1, Position
            
            
            x = mag1_down(keep_down);
            y  = vid_down(keep_down);
            fit = polyfit(x,y,1);
            range = linspace(min(x),max(x),100);
            yfit = fit(1)*range + fit(2);
            R = corrcoef(x,y);
            Rsq = R(1,2).^2;
            
            axes(plotb(1));
            linearityAlign(x, y);
            title('Chan1 Pos')
            
            axes(plota(1)); 
            scatter(mag1_aligned_scaled(keep), vid_aligned(keep), 4, c(keep), '.'); hold on   
            ylabel('Video Pos')
            title(['chan1 r^2: ', num2str(Rsq)])
            plot(range, yfit, 'k', 'lineWidth', 2);
            colormap hsv;
            text(mean(ylim), min(xlim)*.9, ['r^2: ', num2str(Rsq)])
            
            
            %%%%%% Channel 1, Velocity
            
            
            x = mag1V_down(keep_down);
            y  = vidV_down(keep_down);
            fit = polyfit(x,y,1);
            range = linspace(min(x),max(x),100);
            yfit = fit(1)*range + fit(2);
            R = corrcoef(x,y);
            Rsq = R(1,2).^2;
            
            axes(plotb(2));
            linearityAlign(x, y);
            title('Chan1 Vel')
            
            axes(plota(2));
            scatter(mag1_vel_aligned(keep), vidV_aligned(keep), 4, c(keep), '.'); hold on   
            ylabel('Video Vel')          
            title(['chan1 r^2: ', num2str(Rsq)])
            plot(range, yfit, 'k', 'lineWidth', 2);
            colormap hsv;
            text(mean(ylim), min(xlim)*.9, ['r^2: ', num2str(Rsq)])

            %%%%%% Channel 2, Position
            
            
            x = mag2_down(keep_down);
            y  = vid_down(keep_down);
            fit = polyfit(x,y,1);
            range = linspace(min(x),max(x),100);
            yfit = fit(1)*range + fit(2);
            R = corrcoef(x,y);
            Rsq = R(1,2).^2;
            
            axes(plotb(3));
            linearityAlign(x, y);
            title('Chan2 Pos')
            
            axes(plota(3));
            scatter(mag2_aligned_scaled(keep), vid_aligned(keep), 4, c(keep), '.'); hold on   
            xlabel('Magnet Pos')
            ylabel('Video Pos')
            title(['chan2 r^2: ', num2str(Rsq)])
            plot(range, yfit, 'k', 'lineWidth', 2);
            colormap hsv;
            text(mean(ylim), min(xlim)*.9, ['r^2: ', num2str(Rsq)])
            
            
            %%%%%% Channel 2, Velocity
            
            
            x = mag2V_down(keep_down);
            y  = vidV_down(keep_down);
            fit = polyfit(x,y,1);
            range = linspace(min(x),max(x),100);
            yfit = fit(1)*range + fit(2);
            R = corrcoef(x,y);
            Rsq = R(1,2).^2;
            
            axes(plotb(4));
            linearityAlign(x, y);
            title('Chan2 Vel')
            
            axes(plota(4));
            scatter(mag2_vel_aligned(keep), vidV_aligned(keep), 4, c(keep), '.'); hold on   
            xlabel('Magnet Vel')
            ylabel('Video Vel')

            title(['chan2 r^2: ', num2str(Rsq)])
            plot(range, yfit, 'k', 'lineWidth', 2);
            colormap hsv;
            text(mean(ylim), min(xlim)*.9, ['r^2: ', num2str(Rsq)])

%             figure()
%             plot(mag2_vel_aligned(keep));
%             yyaxis right
%             plot(vidV_aligned(keep));
            
            %%%%%% Save Figure
            set(A,'Units','Inches');
            pos = get(A,'Position');
            set(A,'PaperPositionMode','Auto','PaperUnits','Inches','PaperSize',[pos(3), pos(4)]);
            print(A, fullfile(cd, 'MagnetVideoLinearity2.pdf'),'-dpdf');
            savefig('MagnetVideoLinearity2.fig');

        end

        % Button pushed function: PLOT_DownsampleShortScatter_Button
        function PLOT_DownsampleShortScatter_ButtonPushed(app, event)
            mag_aligned = app.vars.mag_aligned;
            vid_aligned = app.vars.vid_aligned;
            time_aligned = app.vars.time_aligned;
            vidV_aligned = app.vars.vidV_aligned;
            keep = app.vars.keep;
            tvid = app.vars.tvid;
            
            mag_down = interp1(time_aligned, mag_aligned, tvid);
            vid_down = interp1(time_aligned, vid_aligned, tvid);
            vidV_down = interp1(time_aligned, vidV_aligned, tvid);
            keep_down = interp1(time_aligned, double(keep), tvid);
            keep_down(isnan(keep_down)) = 0;
            keep_down = logical(keep_down);
            
           
            c = linspace(1,10,length(mag_down));
            
            figure();
            scatter(mag_down(keep_down), vid_down(keep_down), [], c(keep_down));
            colormap hsv;
            title('Scatterplot after downsampling magnet data')
            xlabel('magnet')
            ylabel('video')
     
            figure();
            scatter(mag_down(keep_down), vid_down(keep_down), [], vidV_down(keep_down));
            colormap hsv;
            title('Scatterplot after downsampling magnet data')
            xlabel('magnet')
            ylabel('video')
        end

        % Button pushed function: 
        % PLOT_LinearRegressiononEachSegment_Button
        function PLOT_LinearRegressiononEachSegment_ButtonPushed(app, event)

            weighted_sum = 0;
            try
            for i = 1:length(app.vars.keep_start)-1
                fit = polyfit(app.vars.mag_aligned(app.vars.keep_start(i):app.vars.keep_end(i)),app.vars.vid_aligned(app.vars.keep_start(i):app.vars.keep_end(i)),1);
                m(i) = fit(1);
                weighted_sum = weighted_sum + (app.vars.keep_end(i) - app.vars.keep_start(i))*fit(1);
            end
            catch
                disp('a')
            end
            figure(); 
            hist(m,50);
            ylabel('Segment Amount')
            xlabel('??')
            
            disp(['Good Segment Amount  : ', num2str(length(app.vars.keep_start))])
            disp(['Slopes Mean?         : ',  num2str(mean(m))])
            disp(['Slopes Std?          : ',  num2str(std(m))])
            disp(['Slopes Variance?     : ',  num2str(var(m))])
            disp(['Slopes Weighted Mean?: ',  num2str(weighted_sum/sum(int64(app.vars.keep)))])

        end

        % Button pushed function: DoneSetupButton
        function DoneSetupButtonPushed(app, event)
            app.vars.videoSetupComplete = 1;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.VideoCollectionTab = uitab(app.TabGroup);
            app.VideoCollectionTab.Title = 'Video Collection';
            app.CameraParametersLabel = uilabel(app.VideoCollectionTab);
            app.CameraParametersLabel.Text = 'Camera Parameters';
            app.PresetsDropDownLabel = uilabel(app.VideoCollectionTab);
            app.PresetsDropDownLabel.Text = 'Presets';
            app.PresetsDropDown = uidropdown(app.VideoCollectionTab);
            app.PresetsDropDown.Items = {'ELP', 'Custom'};
            app.PresetsDropDown.Value = 'ELP';
            app.CameraResolutionEditFieldLabel = uilabel(app.VideoCollectionTab);
            app.CameraResolutionEditFieldLabel.Text = 'Camera Resolution';
            app.CameraResolutionEditField = uieditfield(app.VideoCollectionTab, 'numeric');
            app.CameraResolutionEditField.Tooltip = {'Camera Resolution'};
            app.CameraResolutionEditField.Value = 1280;
            app.BoxSizeEditFieldLabel = uilabel(app.VideoCollectionTab);
            app.BoxSizeEditFieldLabel.Text = 'Box Size';
            app.BoxSizeEditField = uieditfield(app.VideoCollectionTab, 'numeric');
            app.BoxSizeEditField.Tooltip = {'Length and Width of the square during initial view. (a crop of the resolution)'};
            app.BoxSizeEditField.Value = 500;
            app.BoxOffsetEditFieldLabel = uilabel(app.VideoCollectionTab);
            app.BoxOffsetEditFieldLabel.Text = 'Box Offset';
            app.BoxOffsetEditField = uieditfield(app.VideoCollectionTab, 'numeric');
            app.BoxOffsetEditField.Tooltip = {'Vertical Offset (pixels) below the line at (res(2)-box)/2 pixels above the bottom of the camera''s full field of view (indicated by'; 'res)'};
            app.CameraResolutionEditField_2 = uieditfield(app.VideoCollectionTab, 'numeric');
            app.CameraResolutionEditField_2.Value = 1024;
            app.UIAxes = uiaxes(app.VideoCollectionTab);
            title(app.UIAxes, 'Camera 1')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes_2 = uiaxes(app.VideoCollectionTab);
            title(app.UIAxes_2, 'Camera 1')
            xlabel(app.UIAxes_2, '')
            ylabel(app.UIAxes_2, '')
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.YTick = [];
            app.SetupCamerasButton = uibutton(app.VideoCollectionTab, 'push');
            app.SetupCamerasButton.ButtonPushedFcn = createCallbackFcn(app, @SetupCamerasButtonPushed, true);
            app.SetupCamerasButton.Text = 'Setup Cameras';
            app.CollectDataButton = uibutton(app.VideoCollectionTab, 'push');
            app.CollectDataButton.ButtonPushedFcn = createCallbackFcn(app, @CollectDataButtonPushed, true);
            app.CollectDataButton.Text = 'Collect Data';
            app.NumberofframestocollectEditFieldLabel = uilabel(app.VideoCollectionTab);
            app.NumberofframestocollectEditFieldLabel.Text = 'Number of frames to collect';
            app.NumberofframestocollectEditField = uieditfield(app.VideoCollectionTab, 'numeric');
            app.NumberofframestocollectEditField.Tooltip = {'@ 30fps for 120 seconds'};
            app.NumberofframestocollectEditField.Value = 3600;
            app.DoneSetupButton = uibutton(app.VideoCollectionTab, 'push');
            app.DoneSetupButton.ButtonPushedFcn = createCallbackFcn(app, @DoneSetupButtonPushed, true);
            app.DoneSetupButton.Text = 'Done Setup';
            app.OfflineEyeTrackingTab = uitab(app.TabGroup);
            app.OfflineEyeTrackingTab.Title = 'Offline Eye Tracking';
            app.RadiusPupilEditFieldLabel = uilabel(app.OfflineEyeTrackingTab);
            app.RadiusPupilEditFieldLabel.Text = 'Radius: Pupil';
            app.RadiusPupilEditField = uieditfield(app.OfflineEyeTrackingTab, 'numeric');
            app.RadiusPupilEditField.Tooltip = {'radius in pixels'};
            app.RadiusPupilEditField.Value = 30;
            app.RadiusCornealReflectionEditFieldLabel = uilabel(app.OfflineEyeTrackingTab);
            app.RadiusCornealReflectionEditFieldLabel.Text = 'Radius: Corneal Reflection ';
            app.RadiusCornealReflectionEditField = uieditfield(app.OfflineEyeTrackingTab, 'numeric');
            app.RadiusCornealReflectionEditField.Value = 6;
            app.MinimumFeaturesEditFieldLabel = uilabel(app.OfflineEyeTrackingTab);
            app.MinimumFeaturesEditFieldLabel.Text = 'Minimum Features';
            app.MinimumFeaturesEditField = uieditfield(app.OfflineEyeTrackingTab, 'numeric');
            app.MinimumFeaturesEditField.Tooltip = {'Fraction of features needed for pupil detection'};
            app.MinimumFeaturesEditField.Value = 0.5;
            app.radiiCR1EditField_2 = uieditfield(app.OfflineEyeTrackingTab, 'numeric');
            app.radiiCR1EditField_2.Value = 12;
            app.ImproveImageContrastCheckBox = uicheckbox(app.OfflineEyeTrackingTab);
            app.ImproveImageContrastCheckBox.Text = 'Improve Image Contrast';
            app.ImproveImageContrastCheckBox.Value = true;
            app.SetupEyeTrackingButton = uibutton(app.OfflineEyeTrackingTab, 'push');
            app.SetupEyeTrackingButton.ButtonPushedFcn = createCallbackFcn(app, @SetupEyeTrackingButtonPushed, true);
            app.SetupEyeTrackingButton.Text = {'Setup'; 'Eye Tracking'; ''};
            app.RunEyeTrackingButton = uibutton(app.OfflineEyeTrackingTab, 'push');
            app.RunEyeTrackingButton.ButtonPushedFcn = createCallbackFcn(app, @RunEyeTrackingButtonPushed, true);
            app.RunEyeTrackingButton.Text = {'Run'; 'Eye Tracking'};
            app.edgeThreshEditFieldLabel = uilabel(app.OfflineEyeTrackingTab);
            app.edgeThreshEditFieldLabel.Text = 'edgeThresh';
            app.edgeThreshEditField = uieditfield(app.OfflineEyeTrackingTab, 'numeric');
            app.edgeThreshEditField.Tooltip = {'Initial gradient threshold of pupil edge detection for cam 1'};
            app.edgeThreshEditField.Value = 35;
            app.PlotAnalysisCheckBox = uicheckbox(app.OfflineEyeTrackingTab);
            app.PlotAnalysisCheckBox.Text = 'Plot Analysis';
            app.PlotAnalysisCheckBox.Value = true;
            app.debugCheckBox = uicheckbox(app.OfflineEyeTrackingTab);
            app.debugCheckBox.Text = 'debug';
            app.UIAxes2_2 = uiaxes(app.OfflineEyeTrackingTab);
            title(app.UIAxes2_2, '')
            xlabel(app.UIAxes2_2, '')
            ylabel(app.UIAxes2_2, '')
            app.UIAxes2_2.XTick = [];
            app.UIAxes2_2.YTick = [];
            app.UIAxes3 = uiaxes(app.OfflineEyeTrackingTab);
            title(app.UIAxes3, '')
            xlabel(app.UIAxes3, '')
            ylabel(app.UIAxes3, '')
            app.UIAxes3.XTick = [];
            app.UIAxes3.YTick = [];
            app.UIAxes3_2 = uiaxes(app.OfflineEyeTrackingTab);
            title(app.UIAxes3_2, '')
            xlabel(app.UIAxes3_2, '')
            ylabel(app.UIAxes3_2, '')
            app.UIAxes3_2.XTick = [0 30 60 90 120 150 180 210 240 270 300];
            app.UIAxes3_2.XTickLabel = {'0'; '30'; '60'; '90'; '120'; '150'; '180'; '210'; '240'; '270'; '300'};
            app.UIAxes3_2.YTick = [0 10 20 30 40 50 60 70 80 90 100];
            app.UIAxes3_2.YTickLabel = {'0'; '10'; '20'; '30'; '40'; '50'; '60'; '70'; '80'; '90'; '100'};
            app.RadiusPupilEditField_2 = uieditfield(app.OfflineEyeTrackingTab, 'numeric');
            app.RadiusPupilEditField_2.Tooltip = {'radius in pixels'};
            app.RadiusPupilEditField_2.Value = 70;
            app.CameraDropDownLabel = uilabel(app.OfflineEyeTrackingTab);
            app.CameraDropDownLabel.Text = 'Camera';
            app.CameraDropDown = uidropdown(app.OfflineEyeTrackingTab);
            app.CameraDropDown.Items = {'1', '2'};
            app.CameraDropDown.Value = '1';
            app.UseallthreshCheckBox = uicheckbox(app.OfflineEyeTrackingTab);
            app.UseallthreshCheckBox.Visible = 'off';
            app.UseallthreshCheckBox.Text = 'Use all thresh ';
            app.EyeAnalysisTab = uitab(app.TabGroup);
            app.EyeAnalysisTab.Title = 'Eye Analysis';
            app.CalibrationStimFrequencyEditFieldLabel = uilabel(app.EyeAnalysisTab);
            app.CalibrationStimFrequencyEditFieldLabel.Text = 'Calibration Stim Frequency';
            app.CalibrationStimFrequencyEditField = uieditfield(app.EyeAnalysisTab, 'numeric');
            app.CalibrationStimFrequencyEditField.Value = 1;
            app.LeftEyeCheckBox = uicheckbox(app.EyeAnalysisTab);
            app.LeftEyeCheckBox.Tooltip = {'If unchecked'; ' use right eye'};
            app.LeftEyeCheckBox.Text = 'Left Eye';
            app.LeftEyeCheckBox.Value = true;
            app.Camera2TimestampsCheckBox = uicheckbox(app.EyeAnalysisTab);
            app.Camera2TimestampsCheckBox.Tooltip = {'If unchecked'; ' use camera 1'};
            app.Camera2TimestampsCheckBox.Text = 'Camera 2 Timestamps';
            app.Camera2TimestampsCheckBox.Value = true;
            app.Step1AnalyzeDataPanel = uipanel(app.EyeAnalysisTab);
            app.Step1AnalyzeDataPanel.Title = 'Step 1: Analyze Data';
            app.RunAnalysisButton_2 = uibutton(app.Step1AnalyzeDataPanel, 'push');
            app.RunAnalysisButton_2.ButtonPushedFcn = createCallbackFcn(app, @RunAnalysisButton_2Pushed, true);
            app.RunAnalysisButton_2.Text = 'Run Analysis';
            app.SaccadeWindowEditField_2 = uieditfield(app.Step1AnalyzeDataPanel, 'numeric');
            app.SaccadeWindowEditField_2.Value = 50;
            app.SaccadeThresholdVideoEditFieldLabel = uilabel(app.Step1AnalyzeDataPanel);
            app.SaccadeThresholdVideoEditFieldLabel.Text = 'Saccade Threshold Video';
            app.SaccadeThresholdVideoEditField = uieditfield(app.Step1AnalyzeDataPanel, 'numeric');
            app.SaccadeThresholdVideoEditField.Value = 500;
            app.SaccadeWindowmsEditFieldLabel = uilabel(app.Step1AnalyzeDataPanel);
            app.SaccadeWindowmsEditFieldLabel.Text = 'Saccade Window (ms)';
            app.SaccadeWindowmsEditField = uieditfield(app.Step1AnalyzeDataPanel, 'numeric');
            app.SaccadeWindowmsEditField.Value = 50;
            app.SaccadeThresholdMagnetChan1EditFieldLabel = uilabel(app.Step1AnalyzeDataPanel);
            app.SaccadeThresholdMagnetChan1EditFieldLabel.Text = 'Saccade Threshold Magnet Chan 1';
            app.SaccadeThresholdMagnetChan1EditField = uieditfield(app.Step1AnalyzeDataPanel, 'numeric');
            app.SaccadeThresholdMagnetChan1EditField.Value = 0.1;
            app.MinimumGoodDataLengthEditFieldLabel = uilabel(app.Step1AnalyzeDataPanel);
            app.MinimumGoodDataLengthEditFieldLabel.Text = 'Minimum Good Data Length';
            app.MinimumGoodDataLengthEditField = uieditfield(app.Step1AnalyzeDataPanel, 'numeric');
            app.MinimumGoodDataLengthEditField.Value = 1;
            app.AlignmentMethodDropDownLabel = uilabel(app.Step1AnalyzeDataPanel);
            app.AlignmentMethodDropDownLabel.Text = 'Alignment Method';
            app.AlignmentMethodDropDown = uidropdown(app.Step1AnalyzeDataPanel);
            app.AlignmentMethodDropDown.Items = {'Saccade Overlap', 'Whole Trace', 'Largest Good Portion', 'Option 4'};
            app.AlignmentMethodDropDown.Value = 'Saccade Overlap';
            app.SaccadeThresholdMagnetChan2EditFieldLabel = uilabel(app.Step1AnalyzeDataPanel);
            app.SaccadeThresholdMagnetChan2EditFieldLabel.Text = 'Saccade Threshold Magnet Chan 2';
            app.SaccadeThresholdMagnetChan2EditField = uieditfield(app.Step1AnalyzeDataPanel, 'numeric');
            app.SaccadeThresholdMagnetChan2EditField.Value = 0.1;
            app.Step2PlotDataPanel = uipanel(app.EyeAnalysisTab);
            app.Step2PlotDataPanel.Title = 'Step 2: Plot Data';
            app.PLOT_DesaccededTraces_Button = uibutton(app.Step2PlotDataPanel, 'push');
            app.PLOT_DesaccededTraces_Button.ButtonPushedFcn = createCallbackFcn(app, @PLOT_DesaccededTraces_ButtonPushed, true);
            app.PLOT_DesaccededTraces_Button.Text = 'Plot Desacceded Traces';
            app.PLOT_FramerateHistogram_Button = uibutton(app.Step2PlotDataPanel, 'push');
            app.PLOT_FramerateHistogram_Button.ButtonPushedFcn = createCallbackFcn(app, @PLOT_FramerateHistogram_ButtonPushed, true);
            app.PLOT_FramerateHistogram_Button.Text = 'Plot Framerate Histogram';
            app.PLOT_AlignedTraces_Button = uibutton(app.Step2PlotDataPanel, 'push');
            app.PLOT_AlignedTraces_Button.ButtonPushedFcn = createCallbackFcn(app, @PLOT_AlignedTraces_ButtonPushed, true);
            app.PLOT_AlignedTraces_Button.Text = 'Plot Aligned Traces';
            app.PLOT_LinearityTraces_Button = uibutton(app.Step2PlotDataPanel, 'push');
            app.PLOT_LinearityTraces_Button.ButtonPushedFcn = createCallbackFcn(app, @PLOT_LinearityTraces_ButtonPushed, true);
            app.PLOT_LinearityTraces_Button.Visible = 'off';
            app.PLOT_LinearityTraces_Button.Text = 'Plot Linearity - Downsample Magnet';
            app.PLOT_DownsampleShortScatter_Button = uibutton(app.Step2PlotDataPanel, 'push');
            app.PLOT_DownsampleShortScatter_Button.ButtonPushedFcn = createCallbackFcn(app, @PLOT_DownsampleShortScatter_ButtonPushed, true);
            app.PLOT_DownsampleShortScatter_Button.Visible = 'off';
            app.PLOT_DownsampleShortScatter_Button.Tooltip = {'Not sure the purpose of this figure - Max'};
            app.PLOT_DownsampleShortScatter_Button.Text = 'Plot Downsample & Short Scatter Plots';
            app.PLOT_LinearRegressiononEachSegment_Button = uibutton(app.Step2PlotDataPanel, 'push');
            app.PLOT_LinearRegressiononEachSegment_Button.ButtonPushedFcn = createCallbackFcn(app, @PLOT_LinearRegressiononEachSegment_ButtonPushed, true);
            app.PLOT_LinearRegressiononEachSegment_Button.Visible = 'off';
            app.PLOT_LinearRegressiononEachSegment_Button.Text = 'Linear Regression on Each Segment';
            app.PlotLinearityUpsampleVideoButton = uibutton(app.Step2PlotDataPanel, 'push');
            app.PlotLinearityUpsampleVideoButton.Text = 'Plot Linearity - Upsample Video';
            app.UIAxes4 = uiaxes(app.EyeAnalysisTab);
            title(app.UIAxes4, 'Title')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            app.UIAxes4.XTick = [];
            app.UIAxes4.YTick = [];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = eyeCalibration

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end