classdef dataViz < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        UIAxes                   matlab.ui.control.UIAxes
        SliderLabel              matlab.ui.control.Label
        Slider                   matlab.ui.control.Slider
        StimShapeButtonGroup     matlab.ui.container.ButtonGroup
        SineButton               matlab.ui.control.RadioButton
        StepButton               matlab.ui.control.RadioButton
        FrequencyDropDownLabel   matlab.ui.control.Label
        FrequencyDropDown        matlab.ui.control.DropDown
        StimTypeDropDownLabel    matlab.ui.control.Label
        StimTypeDropDown         matlab.ui.control.DropDown
        ListBox                  matlab.ui.control.ListBox
        CyclesAfterSpinnerLabel  matlab.ui.control.Label
        CyclesAfterSpinner       matlab.ui.control.Spinner
        GenerateTimelapseButton  matlab.ui.control.Button
        AmplitudeDropDownLabel   matlab.ui.control.Label
        AmplitudeDropDown        matlab.ui.control.DropDown
    end

    
    properties (Access = public)
        dataTable; % Description
        
    end
    
    methods (Access = public)
        
        function UpdateFilterBox(app)
            % Amplitude Filter
            tempTable = app.dataTable;
            tempTable(tempTable.Amplitude ~= str2double(app.AmplitudeDropDown.Value), :) = [];
            
            % StimType Filter           
            tempTable(~contains(tempTable.StimType, app.StimTypeDropDown.Value),:) = [];
            
            % Freq_Duration Filter
            tempTable(~contains(tempTable.Freq_Duration,app.FrequencyDropDown.Value),:) = [];
            
            
            % Put Cell Names into box
            app.ListBox.Items = tempTable.Cell;
        end
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Load Excel file database/table
            app.dataTable = readtable('C:\Users\maxwellg\Documents\RL_Code\Projects\Max - CF Project\RAYMOND_DATA Max Edit.xlsx');
            
            % populate options windows with what exists in the excel file
            uniqueStimType = unique(app.dataTable.StimType);
            uniqueStimType = uniqueStimType(~cellfun('isempty',uniqueStimType));
            app.StimTypeDropDown.Items = uniqueStimType;
            
            uniqueAmplitudes = unique(app.dataTable.Amplitude);
            uniqueAmplitudes(isnan(uniqueAmplitudes)) = []
            uniqueAmplitudes = strsplit(num2str(uniqueAmplitudes'))';
            uniqueAmplitudes = uniqueAmplitudes(~cellfun('isempty', uniqueAmplitudes))
            app.AmplitudeDropDown.Items = uniqueAmplitudes;
            
            uniqueFrequency = unique(app.dataTable.Freq_Duration);
            uniqueFrequency = uniqueFrequency(~cellfun('isempty', uniqueFrequency));
            app.FrequencyDropDown.Items = uniqueFrequency;
            
        end

        % Button pushed function: GenerateTimelapseButton
        function GenerateTimelapseButtonPushed(app, event)
            plot(app.UIAxes, 1:100);
            
            
            words = app.dataTable{1,1};
            words = words{1};
            title(app.UIAxes, words);
        end

        % Value changed function: StimTypeDropDown
        function StimTypeDropDownValueChanged(app, event)
            UpdateFilterBox(app)
        end

        % Value changed function: FrequencyDropDown
        function FrequencyDropDownValueChanged(app, event)
            UpdateFilterBox(app)
        end

        % Value changed function: AmplitudeDropDown
        function AmplitudeDropDownValueChanged(app, event)
            UpdateFilterBox(app)
        end

        % Selection changed function: StimShapeButtonGroup
        function StimShapeButtonGroupSelectionChanged(app, event)
            UpdateFilterBox(app)
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.SliderLabel = uilabel(app.UIFigure);
            app.SliderLabel.Text = 'Slider';
            app.Slider = uislider(app.UIFigure);
            app.StimShapeButtonGroup = uibuttongroup(app.UIFigure);
            app.StimShapeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @StimShapeButtonGroupSelectionChanged, true);
            app.StimShapeButtonGroup.Title = 'Stim Shape';
            app.SineButton = uiradiobutton(app.StimShapeButtonGroup);
            app.SineButton.Text = 'Sine';
            app.SineButton.Value = true;
            app.StepButton = uiradiobutton(app.StimShapeButtonGroup);
            app.StepButton.Text = 'Step';
            app.FrequencyDropDownLabel = uilabel(app.UIFigure);
            app.FrequencyDropDownLabel.Text = 'Frequency';
            app.FrequencyDropDown = uidropdown(app.UIFigure);
            app.FrequencyDropDown.Items = {'.6Hz', '1 Hz', '2 Hz'};
            app.FrequencyDropDown.ValueChangedFcn = createCallbackFcn(app, @FrequencyDropDownValueChanged, true);
            app.FrequencyDropDown.Value = '2 Hz';
            app.StimTypeDropDownLabel = uilabel(app.UIFigure);
            app.StimTypeDropDownLabel.Text = 'Stim Type';
            app.StimTypeDropDown = uidropdown(app.UIFigure);
            app.StimTypeDropDown.Items = {'Dark', 'x1', 'x0', 'x2', 'VORC', 'Pos Steps', 'H Pursuit', 'V Pursuit'};
            app.StimTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @StimTypeDropDownValueChanged, true);
            app.StimTypeDropDown.Value = 'x0';
            app.ListBox = uilistbox(app.UIFigure);
            app.ListBox.Items = {'Pears', 'Apples', ''};
            app.ListBox.Multiselect = 'on';
            app.ListBox.Value = {'Pears'};
            app.CyclesAfterSpinnerLabel = uilabel(app.UIFigure);
            app.CyclesAfterSpinnerLabel.Text = 'Cycles After';
            app.CyclesAfterSpinner = uispinner(app.UIFigure);
            app.CyclesAfterSpinner.Value = 1;
            app.GenerateTimelapseButton = uibutton(app.UIFigure, 'push');
            app.GenerateTimelapseButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateTimelapseButtonPushed, true);
            app.GenerateTimelapseButton.Text = 'Generate Timelapse';
            app.AmplitudeDropDownLabel = uilabel(app.UIFigure);
            app.AmplitudeDropDownLabel.Text = 'Amplitude';
            app.AmplitudeDropDown = uidropdown(app.UIFigure);
            app.AmplitudeDropDown.Items = {'Any', '10', '15', '20', '25', '30'};
            app.AmplitudeDropDown.ValueChangedFcn = createCallbackFcn(app, @AmplitudeDropDownValueChanged, true);
            app.AmplitudeDropDown.Value = '10';
        end
    end

    methods (Access = public)

        % Construct app
        function app = dataViz

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end